classdef EmitterDesignatorDLG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EmissionDesignatorSelectUIFigure  matlab.ui.Figure
        SelectEmissionDesignatorforanalysisButtonGroup  matlab.ui.container.ButtonGroup
        Button1                         matlab.ui.control.RadioButton
        Button2                         matlab.ui.control.RadioButton
        Button3                         matlab.ui.control.RadioButton
        Button4                         matlab.ui.control.RadioButton
        Button5                         matlab.ui.control.RadioButton
        Button6                         matlab.ui.control.RadioButton
        Button7                         matlab.ui.control.RadioButton
        Button8                         matlab.ui.control.RadioButton
        Button9                         matlab.ui.control.RadioButton
        ContinueButton                  matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        EmitterEmissionDesinatorsLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        Property % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        Icon = 'DOC_ICO.ico';
        addpath('\\nthnetapp002g\users\jdevereux\Desktop\Visualization_Tool');
        LoadFigureIcon(app.EmissionDesignatorSelectUIFigure,Icon);                  
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
        AppData = MyGlobalAppData();
        CObject = MyGlobalObject();
        
            delete(app.EmissionDesignatorSelectUIFigure);
            
            N = AppData.ELinkSelected;
            set(CObject.cbe(N),'Value',0);
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
        AppData = MyGlobalAppData();
        Flags = MyGlobalFlags();
        
        AppData.Event = event;
        AppData.EDSelected = app.SelectEmissionDesignatorforanalysisButtonGroup.SelectedObject.Text;
        AppData.EDCount = app.SelectEmissionDesignatorforanalysisButtonGroup.SelectedObject.Value;
        
        MyGlobalAppData(AppData);
                
        Flags.AnalysisReady(1) = 1;
        MyGlobalFlags('AnalysisReady',Flags.AnalysisReady);
        
            if Flags.AnalysisReady(1) && Flags.AnalysisReady(2)
            AppData.app.Button_SelectSimFolder.Visible = 'on';    
            end
            
        delete(app.EmissionDesignatorSelectUIFigure);
               
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EmissionDesignatorSelectUIFigure = uifigure;
            app.EmissionDesignatorSelectUIFigure.Name = 'Emission Designator Select';
            app.SelectEmissionDesignatorforanalysisButtonGroup = uibuttongroup(app.EmissionDesignatorSelectUIFigure);
            app.SelectEmissionDesignatorforanalysisButtonGroup.Title = 'Select Emission Designator for analysis:';
            app.Button1 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button1.Text = 'Button1';
            app.Button1.Value = true;
            app.Button2 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button3.Text = 'Button3';
            app.Button4 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button4.Text = 'Button4';
            app.Button5 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button5.Text = 'Button5';
            app.Button6 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button6.Text = 'Button6';
            app.Button7 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button7.Text = 'Button7';
            app.Button8 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button8.Text = 'Button8';
            app.Button9 = uiradiobutton(app.SelectEmissionDesignatorforanalysisButtonGroup);
            app.Button9.Text = 'Button9';
            app.ContinueButton = uibutton(app.EmissionDesignatorSelectUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CancelButton = uibutton(app.EmissionDesignatorSelectUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.EmitterEmissionDesinatorsLabel = uilabel(app.EmissionDesignatorSelectUIFigure);
            app.EmitterEmissionDesinatorsLabel.Text = 'Emitter Emission Desinators';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EmitterDesignatorDLG

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EmissionDesignatorSelectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EmissionDesignatorSelectUIFigure)
        end
    end
end