classdef Help_AboutRSEC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HelpAboutRSEC                  matlab.ui.Figure
        VersionWA1MATLAB2018ATextAreaLabel  matlab.ui.control.Label
        VersionWA1MATLAB2018ATextArea  matlab.ui.control.TextArea
        July18th2018Label              matlab.ui.control.Label
        ContactLabel                   matlab.ui.control.Label
        jdevereuxntiadocgovLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
      function LoadFigureIcon(app,FigHand,ICON)
        
        warning('off','all');
        pause(1);
        figProps = struct(FigHand);
        controller = figProps.Controller;
        controllerProps = struct(controller);
        platformHost = controllerProps.PlatformHost;
        platformHostProps = struct(platformHost);
        win = platformHostProps.CEF;
        win.Icon = [pwd '\' ICON];
        app = app;%#ok
      
      end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clc; 
        app.HelpAboutRSEC.Position = [625,225,420,470];
        Icon = 'DOC_ICO.ico';
        LoadFigureIcon(app,app.HelpAboutRSEC,Icon);    
            
        % Remove title, axis labels, and tick labels
        title(app.UIAxes, []);
        xlabel(app.UIAxes, []);
        ylabel(app.UIAxes, []);
        app.UIAxes.XAxis.TickLabels = {};
        app.UIAxes.YAxis.TickLabels = {};

        a=imread('NTIA.PNG');
        imagesc(app.UIAxes,a);
       
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HelpAboutRSEC = uifigure;
            app.HelpAboutRSEC.Name = 'Help About RSEC';
            app.VersionWA1MATLAB2018ATextAreaLabel = uilabel(app.HelpAboutRSEC);
            app.VersionWA1MATLAB2018ATextAreaLabel.Text = 'Version WA1 MATLAB 2018A';
            app.VersionWA1MATLAB2018ATextArea = uitextarea(app.HelpAboutRSEC);
            app.VersionWA1MATLAB2018ATextArea.Editable = 'off';
            app.VersionWA1MATLAB2018ATextArea.Value = {'The Radar Spectrum Engineering Criteria (RSEC)'; 'Compliance Program (RCP) was developed by '; 'NTIA''s Office of Spectrum Management to confirm '; 'proper interpretation of the RSEC as written in the '; 'Spectrum Standards (Chapter 5) of the NTIA Manual '; '(Title 47 of the Code of Federal Regulations, Part 300)'; ''};
            app.July18th2018Label = uilabel(app.HelpAboutRSEC);
            app.July18th2018Label.Text = 'July 18th 2018';
            app.ContactLabel = uilabel(app.HelpAboutRSEC);
            app.ContactLabel.Text = 'Contact:';
            app.jdevereuxntiadocgovLabel = uilabel(app.HelpAboutRSEC);
            app.jdevereuxntiadocgovLabel.Text = 'jdevereux@ntia.doc.gov';
            app.UIAxes = uiaxes(app.HelpAboutRSEC);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Help_AboutRSEC

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HelpAboutRSEC)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HelpAboutRSEC)
        end
    end
end