classdef ModulationPop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModulationPopUp              matlab.ui.Figure
        FMChirpSettingsPanel         matlab.ui.container.Panel
        LiniarityButtonGroup         matlab.ui.container.ButtonGroup
        LinearRadio                  matlab.ui.control.RadioButton
        NonLinearRadio               matlab.ui.control.RadioButton
        ChirpBWEditFieldLabel        matlab.ui.control.Label
        ChirpBW                      matlab.ui.control.NumericEditField
        MHzLabel                     matlab.ui.control.Label
        PulseParametersPanel         matlab.ui.container.Panel
        uSecLabel                    matlab.ui.control.Label
        PulseWidthLabel              matlab.ui.control.Label
        PulseWidth                   matlab.ui.control.NumericEditField
        uSecLabel2                   matlab.ui.control.Label
        PulseRiseTimeLabel           matlab.ui.control.Label
        PulseRiseTime                matlab.ui.control.NumericEditField
        uSecLabel3                   matlab.ui.control.Label
        PulseFallTimeEditFieldLabel  matlab.ui.control.Label
        PulseFallTime                matlab.ui.control.NumericEditField
        uSecLabel4                   matlab.ui.control.Label
        PulseRepetitionRateEditFieldLabel  matlab.ui.control.Label
        PulseRepetitionRate          matlab.ui.control.NumericEditField
        PhaseChipSettingsPanel       matlab.ui.container.Panel
        WidthuSecEditFieldLabel      matlab.ui.control.Label
        WidthuSec                    matlab.ui.control.NumericEditField
        ofChipsEditFieldLabel        matlab.ui.control.Label
        ofChips                      matlab.ui.control.NumericEditField
        FrequencyHopSettingsPanel    matlab.ui.container.Panel
        FRQHopLowMHzEditFieldLabel   matlab.ui.control.Label
        FRQHopLowMHz                 matlab.ui.control.NumericEditField
        FRQHopHighMHzEditFieldLabel  matlab.ui.control.Label
        FRQHopHighMHz                matlab.ui.control.NumericEditField
    end


    methods (Access = public)
    
        function LoadFigureIcon(app,FigHand,ICON)
        
        warning('off','all');
        pause(1);
        figProps = struct(FigHand);
        controller = figProps.Controller;
        controllerProps = struct(controller);
        platformHost = controllerProps.PlatformHost;
        platformHostProps = struct(platformHost);
        win = platformHostProps.CEF;
        win.Icon = [pwd '\' ICON];
        app = app;%#ok
        disp('FIN');    
            
        end
        
    end

    methods (Access = private)
    
        function LoadPopUp(app)
        AppData = MyGlobalAppData();
        
        if isfield(AppData,'PW')
        app.PulseWidth.Value = AppData.PW;
        end
        
        if isfield(AppData,'PRT')
        app.PulseRiseTime.Value = AppData.PRT;
        end
        
        if isfield(AppData,'PFT')
        app.PulseFallTime.Value = AppData.PFT;
        end
        
        if isfield(AppData,'PRR') && ~isempty(AppData.PRR)
        app.PulseRepetitionRate.Value = AppData.PRR;
        end
        
        if isfield(AppData,'Bs') && ~isempty(AppData.Bs)
        app.WidthuSec.Value = AppData.Bs;
        end
        
        if isfield(AppData,'CBW') && ~isempty(AppData.CBW)
        app.ChirpBW.Value = AppData.CBW;
        end
        
        if isfield(AppData,'PHCW') && ~isempty(AppData.PHCW)
        app.ofChips.Value = AppData.PHCW;
        end
        
        if isfield(AppData,'RadioLinear')
            if AppData.RadioLinear > 0
            app.LinearRadio.Value = 1;
            else
            app.LinearRadio.Value = 0;     
            end
        end
        
        if isfield(AppData,'RadioNonLinear')
            
            if AppData.RadioNonLinear > 0
            app.NonLinearRadio.Value = 1;
            else
            app.NonLinearRadio.Value = 0;     
            end
        end

        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clc; addpath(pwd);
        app.ModulationPopUp.Position = [650,328,415,335];  
        Icon = 'DOC_ICO.ico';
        LoadFigureIcon(app,app.ModulationPopUp,Icon);
        LoadPopUp(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModulationPopUp = uifigure;
            app.ModulationPopUp.Name = 'Modulation Parameter Pop Up';
            app.FMChirpSettingsPanel = uipanel(app.ModulationPopUp);
            app.FMChirpSettingsPanel.Title = 'FM Chirp Settings';
            app.LiniarityButtonGroup = uibuttongroup(app.FMChirpSettingsPanel);
            app.LiniarityButtonGroup.Title = 'Liniarity:';
            app.LinearRadio = uiradiobutton(app.LiniarityButtonGroup);
            app.LinearRadio.Text = 'Linear';
            app.LinearRadio.Value = true;
            app.NonLinearRadio = uiradiobutton(app.LiniarityButtonGroup);
            app.NonLinearRadio.Text = 'Non-Linear';
            app.ChirpBWEditFieldLabel = uilabel(app.FMChirpSettingsPanel);
            app.ChirpBWEditFieldLabel.Text = 'Chirp BW:';
            app.ChirpBW = uieditfield(app.FMChirpSettingsPanel, 'numeric');
            app.MHzLabel = uilabel(app.FMChirpSettingsPanel);
            app.MHzLabel.Text = 'MHz';
            app.PulseParametersPanel = uipanel(app.ModulationPopUp);
            app.PulseParametersPanel.Title = 'Pulse Parameters';
            app.uSecLabel = uilabel(app.PulseParametersPanel);
            app.uSecLabel.Text = 'uSec';
            app.PulseWidthLabel = uilabel(app.PulseParametersPanel);
            app.PulseWidthLabel.Text = 'Pulse Width:';
            app.PulseWidth = uieditfield(app.PulseParametersPanel, 'numeric');
            app.uSecLabel2 = uilabel(app.PulseParametersPanel);
            app.uSecLabel2.Text = 'uSec';
            app.PulseRiseTimeLabel = uilabel(app.PulseParametersPanel);
            app.PulseRiseTimeLabel.Text = 'Pulse Rise Time:';
            app.PulseRiseTime = uieditfield(app.PulseParametersPanel, 'numeric');
            app.uSecLabel3 = uilabel(app.PulseParametersPanel);
            app.uSecLabel3.Text = 'uSec';
            app.PulseFallTimeEditFieldLabel = uilabel(app.PulseParametersPanel);
            app.PulseFallTimeEditFieldLabel.Text = 'Pulse Fall Time:';
            app.PulseFallTime = uieditfield(app.PulseParametersPanel, 'numeric');
            app.uSecLabel4 = uilabel(app.PulseParametersPanel);
            app.uSecLabel4.Text = 'PPS';
            app.PulseRepetitionRateEditFieldLabel = uilabel(app.PulseParametersPanel);
            app.PulseRepetitionRateEditFieldLabel.Text = 'Pulse Repetition Rate:';
            app.PulseRepetitionRate = uieditfield(app.PulseParametersPanel, 'numeric');
            app.PhaseChipSettingsPanel = uipanel(app.ModulationPopUp);
            app.PhaseChipSettingsPanel.Title = 'Phase Chip Settings';
            app.WidthuSecEditFieldLabel = uilabel(app.PhaseChipSettingsPanel);
            app.WidthuSecEditFieldLabel.Text = 'Width (uSec)';
            app.WidthuSec = uieditfield(app.PhaseChipSettingsPanel, 'numeric');
            app.ofChipsEditFieldLabel = uilabel(app.PhaseChipSettingsPanel);
            app.ofChipsEditFieldLabel.Text = '# of Chips:';
            app.ofChips = uieditfield(app.PhaseChipSettingsPanel, 'numeric');
            app.ofChips.Limits = [0 Inf];
            app.FrequencyHopSettingsPanel = uipanel(app.ModulationPopUp);
            app.FrequencyHopSettingsPanel.Title = 'Frequency Hop Settings';
            app.FRQHopLowMHzEditFieldLabel = uilabel(app.FrequencyHopSettingsPanel);
            app.FRQHopLowMHzEditFieldLabel.Text = 'FRQ Hop Low (MHz)';
            app.FRQHopLowMHz = uieditfield(app.FrequencyHopSettingsPanel, 'numeric');
            app.FRQHopHighMHzEditFieldLabel = uilabel(app.FrequencyHopSettingsPanel);
            app.FRQHopHighMHzEditFieldLabel.Text = 'FRQ Hop High (MHz)';
            app.FRQHopHighMHz = uieditfield(app.FrequencyHopSettingsPanel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ModulationPop

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModulationPopUp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModulationPopUp)
        end
    end
end