classdef RSEC_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComplianceToolsbyOSMNTIAUIFigure  matlab.ui.Figure
        FileMenu_2                   matlab.ui.container.Menu
        NewMenu                      matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        ImportMenu                   matlab.ui.container.Menu
        TextFileMenu                 matlab.ui.container.Menu
        ManualDataMenu               matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        ExecuteMenu                  matlab.ui.container.Menu
        PrintMenu                    matlab.ui.container.Menu
        ExportFigureMenu             matlab.ui.container.Menu
        ViewMenu                     matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AboutRCPMenu                 matlab.ui.container.Menu
        NTIARSECComplianceProgramRCPLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        RadarInfoPanel               matlab.ui.container.Panel
        RadarName                    matlab.ui.control.EditField
        StageDropDownLabel           matlab.ui.control.Label
        StageDropDown                matlab.ui.control.DropDown
        SPSEditFieldLabel            matlab.ui.control.Label
        SPSNumber                    matlab.ui.control.EditField
        DateEditFieldLabel           matlab.ui.control.Label
        SPSDate                      matlab.ui.control.EditField
        RadarDropDown                matlab.ui.control.DropDown
        RadarModulationInfoPanel     matlab.ui.container.Panel
        RadarModulationInfoDropDown  matlab.ui.control.DropDown
        MyModPop                     matlab.ui.control.Button
        RadarSettingsPanel           matlab.ui.container.Panel
        FLEditFieldLabel             matlab.ui.control.Label
        FRQLow                       matlab.ui.control.NumericEditField
        FHEditFieldLabel             matlab.ui.control.Label
        FRQHigh                      matlab.ui.control.NumericEditField
        FCEditFieldLabel             matlab.ui.control.Label
        FRQCenter                    matlab.ui.control.NumericEditField
        PWREditFieldLabel            matlab.ui.control.Label
        PP                           matlab.ui.control.NumericEditField
        GTEditFieldLabel             matlab.ui.control.Label
        AntGain                      matlab.ui.control.NumericEditField
        RBWEditFieldLabel            matlab.ui.control.Label
        RBW                          matlab.ui.control.NumericEditField
        UsageDropDown                matlab.ui.control.DropDown
        RunRSECButton                matlab.ui.control.Button
        BWFactor                     matlab.ui.control.Label
        TraceControlPanel            matlab.ui.container.Panel
        RSECOldButton                matlab.ui.control.StateButton
        RSECNewButton                matlab.ui.control.StateButton
        TheoryButton                 matlab.ui.control.StateButton
        MeasuredButton               matlab.ui.control.StateButton
        OverageButton                matlab.ui.control.StateButton
        UnderageButton               matlab.ui.control.StateButton
        CustomButton                 matlab.ui.control.StateButton
        CriteriaLabel                matlab.ui.control.Label
        ALabel                       matlab.ui.control.Label
        BW40dBLabel                  matlab.ui.control.Label
        BW40Val                      matlab.ui.control.Label
        SlopeLabel                   matlab.ui.control.Label
        SlopeVal                     matlab.ui.control.Label
        XdBLabel                     matlab.ui.control.Label
        XdBVal                       matlab.ui.control.Label
        SpanMHzEditFieldLabel        matlab.ui.control.Label
        SpanMHzVal                   matlab.ui.control.NumericEditField
        TextFName                    matlab.ui.control.Label
        YLimdBmLabel                 matlab.ui.control.Label
        YLIMVal                      matlab.ui.control.NumericEditField
        RevX10Label                  matlab.ui.control.Label
    end


    methods (Access = public)
        
        function [AppData] = LoadEQP_RSEC(app,val1,val2)
        app = app; %#ok
        AppData = MyGlobalAppData();
        
        N = zeros(1,1);
        p = 1;
        A = strfind(table2cell(AppData.RadarList(:,1)),val1);
        Len = length(A);
                    
                            for i = 1:Len
                                tmp = A{i};
                                if tmp == 1
                                N(p) = i;
                                p = p + 1;    
                                end   
                            end
                            
                            
        A = strfind(table2cell(AppData.RadarList(N,7)),val2);
        p = 1;
        N = zeros(1,1);
        Len = length(A);
                    
                            for i = 1:Len
                                tmp = A{i};
                                if tmp == 1
                                N(p) = i;
                                p = p + 1;    
                                end   
                            end
                                
        AppData.EDSelectedIndex = N(1);          
            
        MyGlobalAppData(AppData);
        AppData = MyGlobalAppData();
        end

        
        function [AppData] = LoadEQP(app,value)
        app = app; %#ok
        AppData = MyGlobalAppData();
        
        N = zeros(1,1);
        p = 1;
        A = strfind(table2cell(AppData.RadarList(:,1)),value);
        Len = length(A);
                    
                            for i = 1:Len
                                tmp = A{i};
                                if tmp == 1
                                N(p) = i;
                                p = p + 1;    
                                end   
                            end
                            
                            
         Len = length(N);
         AppData.TXRF = [];
         AppData.TXEDS = [];
         
                            for i = 1:Len
                            tmp = cell2mat(table2cell(AppData.RadarList(N(i),26)));    
                            AppData.TXRF(i,:) = str2double(strsplit(tmp(2:(end-1))));
                            AppData.TXEDS{i} = cell2mat(table2cell(AppData.RadarList(N(i),7)));
                            end
                                
        AppData.RP = N(1);                    
            
        MyGlobalAppData(AppData);
        AppData = MyGlobalAppData();
        end

        
        function XBW = GetXBW(app,XdB,B40,m)
            
        Alpha = (XdB-40)/m;
        XBW = (10^Alpha) * B40;
        app = app; %#ok
        
        end
        
        function hand = Export_CallPlot(app)
            
        gArray = findobj(app.UIAxes);
        Len = length(gArray);
        hand = figure;
        AX1 = axes(hand);
        plot(gArray(2).XData,gArray(2).YData);
        hold on;
         
        for j = 3:Len
        plot(gArray(j).XData,gArray(j).YData);
        end
        
        %AX1.XLim = gArray(1).XLim;
        %AX1.YLim = gArray(1).YLim;
        
        AX1.FontWeight = 'bold';
        title('RSEC Mask');
            
        end    
        
        function Exit_Callback(app,varargin)
        
        AppData = MyGlobalAppData();
        
        myicon = imread('Quest.png');
        
        
        if ~(isempty(AppData.ModT) || isempty(AppData.RadarFName))
           fstring(1)={'Do you want to save the System file?'};
           button = questdlg(fstring,'Save System file','Yes','No',struct('Default','No','Interpreter','none','IconString','custom','IconData',myicon));
           
         
           if strcmp(button,'Yes')
           Save_Callback(app);
           end
           
        end
        close(app.ComplianceToolsbyOSMNTIAUIFigure);
        end
        
        function Save_Callback(app,varargin)
        app = app; %#ok    
        AppData = MyGlobalAppData();
        
        AppData.SysName = [AppData.RadarFName '.mat'];
        AppData.SysDir = pwd;
        
        
        if exist([AppData.SysDir AppData.SysName]) == 2 %#ok
           fname=[AppData.SysDir AppData.SysName];
           fstring(1) = {'Are you sure you want to overwrite'};
           fstring(2) = {[fname '?']};
           
           button = questdlg(fstring,'Overwrite Warning!','Yes','No','Maybe','Yes');
           
           if strcmp(button,'Yes')
           save(fname,'AppData');
           end
           
        else

              [AppData.SysName,AppData.SysDir] = uiputfile('*.mat','Save system file as',...
              [AppData.SysDir '\' AppData.SysName]);
 
           
           if AppData.SysName==0
           return %Insert Error Dialog Here.....
           end
           
           fname = [AppData.SysDir AppData.SysName];
           save(fname,'AppData');
           
        end 
        end
        
        function ImportMeasuredData(app)
            
        AppData = MyGlobalAppData();
        Flags = MyGlobalFlags();
        MRData = MyGlobalMRData(app);
        
        % Imports Measured Data from Text file
        if ~isfield(AppData,'ImportPath')
        AppData.ImportPath = [];    
        end
        
        if isempty(AppData.ImportPath)        
        [MRData.DataName,AppData.ImportPath]=uigetfile('*.txt',...
              'Select measured data',pwd,'MultiSelect','off');
        else
        [MRData.DataName,AppData.ImportPath]=uigetfile('*.txt',...
              'Select measured data',AppData.ImportPath,'MultiSelect','off');    
        end
        
        if MRData.DataName==0
        return %Add No measure data found in error dialog
        end
        
        data = load([AppData.ImportPath MRData.DataName]);
        
        figure(app.ComplianceToolsbyOSMNTIAUIFigure);
        
        RadarFName = strsplit(MRData.DataName,'.');
        app.TextFName.Text = RadarFName(1);
        
        AppData.Fm = data(:,1);
        AppData.Sm = data(:,2)-max(data(:,2));
        MRData.Fm = AppData.Fm;
        MRData.Sm = AppData.Sm;
        AppData.RadarParams.Fm = AppData.Fm;
        AppData.RadarParams.Sm = AppData.Sm;
        
        app.UIAxes.XLim = [min(AppData.Fm) max(AppData.Fm)]; % Set Span for Measured data
        app.SpanMHzVal.Value = app.UIAxes.XLim(2) - app.UIAxes.XLim(1);
        
        Idx = find(MRData.Sm == 0,1);
        AppData.FC = MRData.Fm(Idx);
        AppData.RadarParams.FC = AppData.FC;
        
        Flags.MeasuredData = 1;
        
        if Flags.RunRSEC
        app.MeasuredButton.Enable = 'On';
        app.ViewMenu.Enable = 'Off';
        AppData.UserData = plot(app.UIAxes,AppData.Fm,AppData.Sm,'-','LineWidth', 1.0); 
        AppData.UserData.Visible = 'Off';
        else
        app.ViewMenu.Enable = 'On';    
        end
        
        app.TextFileMenu.Enable = 'Off';
        
        
        MyGlobalFlags(Flags);
        MyGlobalAppData(AppData);
        MyGlobalMRData(app,MRData);
        
        end
        
        function Flags = FC_Butt(app,Flags)
        AppData = MyGlobalAppData();
        
        app.FRQCenter.Value = AppData.FC;
        
        FL = min(AppData.Fm);
        FH = max(AppData.Fm);
        
        app.FRQLow.Value = FL;
        app.FRQHigh.Value = FH;
        
        if FL ~= 0 && FH ~= 0
            FC = FL + ((FH-FL)/2);
            app.FRQCenter.Value = FC;
            AppData.FC = FC;
        end
        
        if ischar(AppData.FC)
            if str2double(AppData.FC) > 0
            Flags.FCSet = 1;
            end
        else
            if AppData.FC > 0
            Flags.FCSet = 1;
            end
        end
   
        MyGlobalAppData('FC',AppData.FC);
        end
        
        function PopUpPlot(app,varargin)
        Flags = MyGlobalFlags(); 
        AppData = MyGlobalAppData();

        Flags.ViewOnly = 1;
        
        Flags = FC_Butt(app,Flags);
        
        plot(app.UIAxes,AppData.Fm,AppData.Sm);
        SpanVal = max(AppData.Fm) - min(AppData.Fm);
        app.SpanMHzVal.Value = SpanVal;
        app.SpanMHzVal.Enable = 'On';
  
        Flags.ViewOnly = 0;
        app.TextFileMenu.Enable = 'Off';
        MyGlobalFlags(Flags);
        
        end
     
        function [Y,X] = GetScaledBW(app,BW,XdB,Fn,Idx)
        app = app; %#ok
        Y = 0;
        B40 = abs(BW) * 2;
        X = GetXBW(app,XdB,B40,20);
        
        Count = 0.0001:0.0001:1; %Scaler
        Count = flip(Count);
        
        for i = Count 
        tmp = -20*(log10(Fn(Idx)/(BW*i)))-40;
        if tmp < -XdB
        Y = i; 
        X = GetXBW(app,abs(tmp),B40,20);
        break
        end
        end
        
        Y = 1/Y;

        end
        
        function h = RSECPlot(app,h,Fp,N) 
        AppData = MyGlobalAppData();
        
        hold(app.UIAxes,'on');
        
        switch N
        
        case 1
        AppData.RSEC_Mask = plot(app.UIAxes,Fp,h.oRollOff,'m-','LineWidth', 1.35);  
        
        if app.RSECOldButton.Value == 0
        AppData.RSEC_Mask.Visible = 'Off';
        else
        AppData.RSEC_Mask.Visible = 'ON';
        end
        
        AppData.ProposedRSEC = plot(app.UIAxes,Fp,h.nRollOff,':','Color',[0.64 0.08 0.18],'LineWidth', 1.5); 
        
        if app.RSECNewButton.Value == 0
        AppData.ProposedRSEC.Visible = 'Off';
        else
        AppData.ProposedRSEC.Visible = 'ON';
        end
        
        AppData.Theory = plot(app.UIAxes,h.Ft,h.St,'-'); 
        set(AppData.Theory,'color',[0.7 0.7 0.7]);
        
        if app.TheoryButton.Value == 0
        AppData.Theory.Visible = 'Off';
        else
        AppData.Theory.Visible = 'ON';
        end
 
        
        case 2
        AppData.UserData = plot(app.UIAxes,AppData.Fm,AppData.Sm,'-','LineWidth', 1.0); 
       
        
        if app.MeasuredButton.Value == 0
        AppData.UserData.Visible = 'Off';
        else
        AppData.UserData.Visible = 'ON';
        end
        
        case 3
        AppData.RedRings = plot(app.UIAxes,Fp(h.oo),h.Sm(h.oo),'ro');         
        
        if app.OverageButton.Value == 0
        AppData.RedRings.Visible = 'Off';
        else
        AppData.RedRings.Visible = 'ON';
        end
        
        case 4
        AppData.GreenRings = plot(app.UIAxes,Fp(h.uu),h.Sm(h.uu),'go'); 
        
        if app.UnderageButton.Value == 0
        AppData.GreenRings.Visible = 'Off';
        else
        AppData.GreenRings.Visible = 'ON';
        end
        
        end
        %AppData.CustomMask = plot(app.UIAxes,Fp,h.custMask, 'r:','LineWidth', 1.5);
      
        MyGlobalAppData(AppData);
  
        end
        
        function B = BWs(app,f,S,b,interpolate)
        % f is freq vector (MHz); S is power vector (dB), b is X dB vector
        % If dY is large from point to point, interpolation may not the best choice
        % interpolate = 1 to interpolate X dB bandwidths between data points
        % interpolate = 0 to use outermost points for X dB bandwidths
        len=length(S);
        
        if max(S) ~= 0
            S=S-max(S);
        end
        for i=1:length(b)
            u=find(S>-b(i), 1, 'last' );v=find(S>-b(i), 1 );
            if u==len || v==1
                B(i)=0; %#ok<*AGROW>
            else
                if interpolate
                    B(i)=interp1(S(u:u+1),f(u:u+1),-b(i))-interp1(S(v-1:v),...
                        f(v-1:v),-b(i));
                else
                    B(i) = f(u+1) - f(v-1);
                end
            end
        end
        app = app;%#ok
        end
        
        function Y=getMask(app,B40,XdB,m,Fn,h)
        Flags = MyGlobalFlags();
        MRData = MyGlobalMRData(app);
        
        Y=zeros(size(Fn));
        
        % Fn is frequency vector, Fo is center frequency, B40 is RSEC 40 dB bandwidth
        % and the hopping bandwidth, -XdB is the maximum RSEC attenuation
        % m is the rolloff slope in dB/decade (always positive)
        % MT = Modulation Type Use a hard slope for FHOP
        
        if h.ModType==4 || h.ModType==7 ||h.ModType==10
            
        Y = MaskBuilder(app,B40,XdB,Fn,m);    
          
        else
            
        Alpha = (XdB-40)/m;
        BWXdB = (10^Alpha) * B40;
        IndXdB = find(Fn<=(-BWXdB/2),1,'last');
        fBWneg = -B40/2;
        fBWpos = B40/2;    
        
        [DNoSlope, TMP] = GetScaledBW(app,fBWneg,XdB,Fn,IndXdB);
        
        if Flags.LegacyHold == 0
            if isempty(IndXdB)
            tmp = num2str (BWXdB/2);    
            app.BWFactor.Text = ['BW=' tmp];
            else
                if ~isempty(DNoSlope)
                app.BWFactor.Text = ['BWS =' num2str(TMP)];
                else
                app.BWFactor.Text = ['BW=' 1];
                end
            end
        Flags.LegacyHold = 1; 
        MRData.bw40 = B40;
        MRData.bwXdB = BWXdB;
        end
        %********************************************************
        
        IndexPoint = find(Fn<=fBWneg, 1, 'last' );
        
        for k=1:IndexPoint
           Y(k)=-(m*log10(Fn(k)/fBWneg)+40);
           if Y(k)<-XdB
              Y(k)=-XdB;
           end
        end
        
        IndexPoint = find(Fn>=fBWpos, 1 );
        
        for k=IndexPoint:length(Y)
           Y(k)=-(m*log10(Fn(k)/fBWpos)+40);
           if Y(k)<-XdB
              Y(k)=-XdB;
           end
        end
        
        end
        MyGlobalFlags(Flags);
        MyGlobalMRData(app,MRData); 
        end

        function Y = MaskBuilder(app,B40,XdB,Fn,Slope)
        AppData = MyGlobalAppData();
        Flags = MyGlobalFlags();
        MRData = MyGlobalMRData(app);
        
        LocalIcon = [pwd '\Radar.png'];
        
        BWTest = 1.5* B40;
        SpanTest = Fn(end) - Fn(1);
        
        if SpanTest < BWTest
        ed = errordlg('The Span Settings should be twice the usable bandwidth!','Please Try Again');
        SetFigIcon(ed,LocalIcon);
        waitfor(ed);
        Y = Fn; 
        return
        end
        
        %******* Passed the test now we build the Slope *******
        
        Y=zeros(size(Fn));
        
        Bs = AppData.Bs;
        
        fBWneg = -0.5*B40;
        fBWpos = 0.5*B40;
        
        %See RSEC MASK documentation by Bob Sole for Equations used...
        Alpha = (XdB-40)/Slope;
        XBW = B40 - Bs;
        BXdB = (10^Alpha * XBW) + Bs;
        % ****************************
        MRData.bwXdB = BXdB;
        MRData.bw40 = B40;
        IndexBWXdB = find(Fn<=(-BXdB/2),1,'last');
        
        if IndexBWXdB > length(Fn)
        IndexBWXdB = length(Fn);
        end
        
        if Flags.TextData
        Span = (Fn(end) - Fn(1)); 
        Fn = linspace(-(Span/2),(Span/2),length(Y));
        else
        Span = app.SpanMHzVal.Value;
        end
        
        GapFill = ceil(B40/(Span/length(Fn))); %MHz per point
            
        DNoSlope = GetScaledBW(app,fBWneg,XdB,Fn,IndexBWXdB);    
            
        fBWneg = fBWneg/DNoSlope; 
        fBWpos = fBWpos/DNoSlope;
            
        for k=1:find(Fn<=fBWneg, 1, 'last' )
           Y(k)=-((Slope*1.5)*log10(Fn(k)/fBWneg)+40);
           if Y(k)<-XdB 
              Y(k)=-XdB;
           end
        end
        
        for k=find(Fn>=fBWpos, 1 ):length(Y)
           Y(k)=-((Slope*1.5)*log10(Fn(k)/fBWpos)+40);
           if Y(k)<-XdB
              Y(k)=-XdB;
           end
        end   
        
        [~,n] = size(Y);
        if n == 1
        Y = Y';
        end
        
        Indx = find(Y==0, 1, 'first' );
        Y1 = Y(1:Indx);
        Indx = find(Y==0, 1, 'last' );
        Y2 = Y(Indx:end);
        Q = zeros((GapFill-2),1)';
        
        A = [Y1 Q Y2];
        if length(A) > length(Y)
        TriSlope = ceil((length(A)-length(Y))/2);
        A = A((TriSlope+1):(end-TriSlope));
        end
        Y = A;
        
        if length(Fn) > length(Y)
        Y(end+1) = -XdB;   
        end
        
        AppData.Fn = Fn;
        
        MyGlobalAppData(AppData);
        MyGlobalFlags(Flags);
        MyGlobalMRData(app,MRData);

        end
    
        function y=checkdc(app,dc,Fs,tau,dr,df)
            
        tb=tau + .5*(dr+df);
        dt=1/Fs;    
        t = -tb/2:dt:tb/2-dt;
        M=length(t);
        N=ceil(M/dc);
        th=5e6;
        
        if N>th
           y=M/th;
        else
           y=dc;
        end
        app = app; %#ok
        end
    
        function [f,SdB] = Gen_Trap_Spect(app,fc,Bc,tau,dr,df,dc,Fs)
        % FFT of trapezoidal pulse
        % Calculate the spectrum of a complex trapezoidal chirp.  Plot the power spectrum
        % Bc is zero for un-modulated pulse
        
        tb=tau + .5*(dr+df);  %pulse width at the base.  Chirp occurs over this width
        dt=1/Fs;    
        
        t = -tb/2:dt:tb/2-dt;   %time for pulse
        M=length(t);
        
        A=ones(1,M);    %Create pulse envelope
        
        cc=floor(dr/dt+1);
        A(1:cc)=t(1:cc)/dr+0.5*tb/dr;
        dd=ceil((tb-df)*Fs)+1;%ceil(df/dt-1);
        A(dd:end)=-t(dd:end)/df+0.5*tb/df;
        
        N=ceil(M/dc);%2^16; %# FFT points
        
        x = A.*exp(1i*(2*pi*(fc*t+Bc*(t.^2)/(2*tb))));
           
        SdB = 20*log10(abs(fftshift(fft(x,N)))); %This is a FFT shifted
        dFf=Fs/N;    
        f = -Fs/2:dFf:+Fs/2-dFf;%-.5*N/dFf:.5*N/dFf-1;     % frequency scale, Hz
        app = app; %#ok
        end
        
        function [x,y]=nRSECcriteria(app,h)
            
        Flags = MyGlobalFlags(); 
        app = app;%#ok
        
        IconLocation = [pwd '\Radar.png'];
        
        y = []; x = []; %#ok
        
   
        if isfield(h,'PW') && ischar(h.PW)
        h.PW = str2double(h.PW);
        elseif ~isfield(h,'PW')
        h.PW = 0;  
        else
        h.PW = h.PW;    
        end
        
              
        if iscell(h.PRR)
            h.PRR = h.PRR{:};
            if ischar(h.PRR)
            h.PRR = str2double(h.PRR{:});
            end
        elseif ~isfield(h,'PRR')
        h.PRR = 0; 
        end
        
        if isempty(h.PW)
        h.PW = 0;
        end
        
        if isempty(h.PRR)
        h.PRR = 0;
        end
        
        DC = (h.PW/h.PRR) * 100;
        
        if DC < 10 && DC ~= 0
        Flags.DC = 1;
        else
        Flags.DC = 0;
        end
        
        p = 10^(h.PP/10)/1000;
        
         
        if h.ModType == 2 || h.ModType == 5 || h.ModType == 8 
            
        NonPulse=1;
        else
        NonPulse=0;
        end
        
        if h.ModType == 3 || h.ModType == 4 || h.ModType == 6 || h.ModType == 7 ||...
           h.ModType == 9 || h.ModType == 10
        
        Pulsed = 1;
        else
        Pulsed = 0;
        end
        
        %Criteria A Non-pulsed radars of 40 watts or less rated average power
        %Pulsed radars of 1 kW or less rated peak power
        %Radars with an operating frequency above 40 GHz
        %Man-portable10 radars- Man-transportable11 radars - Expendable
        %non-recoverable radars on missiles
        
        if Flags.RC == 0
            if strcmp(h.UseageN(1:3),'Man') && (p > 40 * 1e3)
            ed = errordlg('The Radar cannot be Man transportable with that much power!','Useage Error 127');
            SetFigIcon(ed,IconLocation);
            waitfor(ed);
            h.UseageN = 'Fixed';
            end
            Flags.RC = 1;
        end
        
        
        if NonPulse && p <= 40 || Pulsed && p <= 1000 || h.FC > 40000 ||strcmp(h.UseageN(1:3),'Man')||strcmp(h.UseageN(1:3),'Exp')
        
            if h.FC >= 2700 && h.FC <= 2900
            x = 'D';
            return
            else
            x = 'A';
            end
        
            if p > 100 || Flags.DC
            y = 55;
            elseif p <= 100 && p > 1
            y = 50;
            elseif p <= 1
            y = 40;
            end
        
            if p > 1
            Flags.RSEC_A1 = 1;
            else
            Flags.RSEC_A1 = 0;
            end
        
        elseif (p>1000&&p<=100000)&&(h.FC>2900&&h.FC<40000)
        x='B';
        elseif strcmp(h.UseageN,'Fixed') && (h.FC>=2700&&h.FC<=2900)
        x='D';
        elseif strcmp(h.UseageN,'WPR')&& h.FC==449
        x='E';
        else
        x='C';
        end
        MyGlobalFlags(Flags);
        end
        
        function B = newRSECbw(app,h)
        Flags = MyGlobalFlags();
        
        % h.PHCW is chip width phase coded
        if isfield(h,'PHCW')
         
         if ischar(h.PHCW)
         h.PHCW = str2double(h.PHCW);
         else
         h.PHCW = h.PHCW;    
         end
            
       else
       h.PHCW = 0;
       end
        
        if isempty(h.Bs)
        h.Bs = 0;
        else
         if ischar(h.Bs)
         h.Bs = str2double(h.Bs);
         end
        end
        
        if isempty(h.Criteria)
        B=[]; %Insert an Error return here
        return
        end
        
        tr = min(h.PRT,h.PFT); %Ref: Red Book chapter five
        
        switch h.Criteria
            
           case 'A'
              switch h.ModType 
                 case {2,8}
                      if Flags.RSEC_A1 
                      B=0.0003*h.FC;  
                      else
                      B=0.0003*h.FC;   
                      end
                 case {3,4}
                    if h.Bs>0
                       B=7.6/sqrt(tr*h.PW)+h.Bs;
                    else
                       B=min(7.6/sqrt(tr*h.PW),64/h.PW);
                    end
                 case 9
                    if h.Bs>0
                       B=7.6/sqrt(tr*h.PHCW)+h.Bs;
                    else
                       B=min(7.6/sqrt(tr*h.PHCW),64/h.PW);
                    end
                 case {6,7}
                       B=B40LFM(app,h.CBW,h.PW,h.PRT,h.PFT,1.5);
                 case 5
                       B=0.0003*h.FC+h.CBW;
                 otherwise
                       B=[];   
              end
              
         case 'B'
              switch h.ModType
                 case {3,4}
                    if h.Bs>0
                       B=7.6/sqrt(tr*h.PW)+h.Bs;
                    else
                       B=min(7.6/sqrt(tr*h.PW),64/h.PW);
                    end
                 case 9
                    if h.Bs>0
                       B=7.6/sqrt(tr*h.PHCW)+h.Bs;
                    else
                       B=min(7.6/sqrt(tr*h.PHCW),64/h.PW);
                    end
                 case {6,7}
                       B=B40LFM(app,h.CBW,h.PW,h.PRT,h.PFT,1.5);
                    if isempty(B)
                       B=7.6/sqrt(tr*h.PW)+2*(h.CBW+0.065/tr);
                    end
                       B=B+h.Bs;
                 case {2,8}
                       B=0.0003*h.FC;
                 case 5
                       B=0.0003*h.FC+h.CBW;
                 otherwise
                       B=[];
              end
              
           case 'C'
              switch h.ModType
                 case {3,4}
                    if h.Bs>0
                       B=6.2/sqrt(tr*h.PW)+h.Bs;
                    else
                       B=min(6.2/sqrt(tr*h.PW),64/h.PW);
                    end
                  case 9
                    if h.Bs>0
                       B=6.2/sqrt(tr*h.PHCW)+h.Bs;
                    else
                       B=min(6.2/sqrt(tr*h.PHCW),64/h.PW);
                    end
                 case {6,7}
                    B=B40LFM(app,h.CBW,h.PW,h.PRT,h.PFT,1.5);
                    if isempty(B)
                       B=6.2/sqrt(tr*h.PW)+2*(h.CBW+0.105/tr);
                    end
                    B=B+h.Bs;
                 case {2,8}
                    B=0.0003*h.FC;
                 case 5
                    B=0.0003*h.FC+h.CBW;
                 otherwise
                    B=[];
              end
              
           case 'D'
              switch h.ModType
                 case {3,4}
                    B=6.2/sqrt(tr*h.PW)+h.Bs;
                 case 9
                    B=6.2/sqrt(tr*h.PHCW)+h.Bs;
                 case {6,7}
                    B=B40LFM(app,h.CBW,h.PW,h.PRT,h.PFT,1.5);
                    if isempty(B)
                       B=6.2/sqrt(tr*h.PW)+2*(h.CBW+0.105/tr);
                    end
                    B=B+h.Bs;
                 case {2,8}
                    B=0.0003*h.FC;
                 case 5
                    B=0.0003*h.FC+h.CBW;
                 otherwise
                    B=[];
              end
              
           case 'E'
              switch h.ModType
                 case {3,4}
                    B=min(6.2/sqrt(tr*h.PW),64/h.PW);
                 case 9
                    B=min(6.2/sqrt(tr*h.PHCW),64/h.PW);
                 case {6,7}
                    B=B40LFM(app,h.CBW,h.PW,h.PWr,h.PWf,1.5);
                    if isempty(B)
                       B=6.2/sqrt(tr*h.PW)+2*(h.CBW+0.105/tr);
                    end
                 case {2,8}
                    B=0.0003*h.FC;
                 case 5
                    B=0.0003*h.FC+h.CBW;
        
                 otherwise
                    B=[];
              end
        
        end
        MyGlobalFlags(Flags);
        end
    
        function B=oldRSECbw(app,h)
        app = app; %#ok
        
        if isempty(h.Bs)
        h.Bs = 0;
        else
         if ischar(h.Bs)
         h.Bs = str2double(h.Bs);
         end
        end
        
        if isempty(h.Criteria)
        B=[];
        return
        end
        
        tr = min(h.PRT,h.PFT);
        
        switch h.Criteria
           case 'A'
              switch h.ModType
                 case {3,4}
                    if h.Bs>0
                    B=(7.6/sqrt(h.PW*1e-6*tr*1e-6)/1e6) + h.Bs;
                    else
                    B= min((7.6/sqrt(h.PW*1e-6*tr*1e-6)/1e6),(64/(h.PW*1e-6)/1e6));
                    end
                 case 9
                    if h.Bs>0
                    B=(7.6/sqrt(tr*1e-6*h.PHCW*1e-6)/1e6) + h.Bs;
                    else
                    B= min(7.6/sqrt(tr*1e-6*h.PHCW*1e-6)/1e6,(64/(h.PW*1e-6))/1e6);
                    end
                 case {6,7}
                    B=((7.6/sqrt(h.PW*1e-6*tr*1e-6)+ 2 * (h.CBW*1e6+(0.065/(tr*1e-6))))/1e6) + h.Bs;
                    if isempty(B)
                    B=((7.6/sqrt(h.PW*1e-6*tr*1e-6)+ 2 * (h.CBW*1e6+(0.065/(tr*1e-6))))/1e6);
                    end
                    
                 case {2,8}
                    B=(0.0003*h.FC*1e6)/1e6;
                 case 5
                    B=((0.0003*h.FC*1e6)+ (h.CBW*1e6))/1e6;
                 otherwise
                    B=[];
              end
          
            case 'B'
              switch h.ModType
                 case {3,4}
                    if h.Bs>0
                    B=(7.6/sqrt(tr*1e-6*h.PW*1e-6))/1e6 + h.Bs;
                    else
                    B=min((7.6/sqrt(tr*1e-6*h.PW*1e-6))/1e6,(64/(h.PW*1e-6))/1e6);
                    end
                 case 9
                    if h.Bs>0
                    B=(7.6/sqrt(tr*1e-6*h.PHCW*1e-6))/1e6 + h.Bs;
                    else
                    B=min((7.6/sqrt(tr*1e-6*h.PHCW*1e-6))/1e6,(64/(h.PW*1e-6))/1e6);
                    end
                 case {6,7}
                    B= ((7.6/sqrt(h.PW*1e-6*tr*1e-6) + 2 * (h.CBW*1e6+(0.065/(tr*1e-6))))/1e6) + h.Bs;
                    if isempty(B)
                    B= ((7.6/sqrt(h.PW*1e-6*tr*1e-6) + 2 * (h.CBW*1e6+(0.065/(tr*1e-6))))/1e6);
                    end
                 case {2,8}
                    B=(0.0003*h.FC*1e6)/1e6;
                 case 5
                    B=((0.0003*h.FC*1e6)+ (h.CBW*1e6))/1e6;
                 otherwise
                    B=[];
              end
              
           case 'C'
              switch h.ModType
                 case {3,4}
                    if h.Bs>0
                       B=(6.2/sqrt(tr*1e-6*h.PW*1e-6))/1e6 + h.Bs;
                    else
                       B=min((6.2/sqrt(tr*1e-6*h.PW*1e-6))/1e6,(64/(h.PW*1e-6))/1e6);
                    end
                 case 9
                    if h.Bs>0
                       B=(6.2/sqrt(tr*1e-6*h.ts))/1e6 + h.Bs;
                    else
                       B=min((6.2/sqrt(tr*1e-6*h.ts))/1e6,(64/(h.PW*1e-6)))/1e6;
                    end
                 case {6,7}
                    B = ((6.2/sqrt(h.PW*1e-6*tr*1e-6) + 2*(h.CBW*1e6+(0.105/(tr*1e-6))))/1e6) + h.Bs;
                 case {2,8}
                    B=(0.0003*h.FC*1e6)/1e6;
                 case 5
                    B=((0.0003*h.FC*1e6)+ (h.CBW*1e6))/1e6;
                 otherwise
                    B=[];
              end
              
           case 'D'
              switch h.ModType
                 case {3,4}
                    B=(6.2/sqrt(tr*1e-6*h.PW*1e-6))/1e6 + h.Bs;
                  case {9,10}
                    B=(6.2/sqrt(tr*1e-6*h.ts))/1e6 + h.Bs;
                 case {6,7}
                    B = ((6.2/sqrt(h.PW*1e-6*tr*1e-6) + 2*(h.CBW*1e6+(0.105/(tr*1e-6))))/1e6) + h.Bs;
                 case {2,8}
                    B=(0.0003*h.FC*1e6)/1e6;
                 case 5
                    B=((0.0003*h.FC*1e6)+ h.CBW*1e6)/1e6;
                 otherwise
                    B=[];
              end
           case 'E'
              switch h.ModType
                 case 3
                    B=min((6.2/sqrt(tr*1e-6*h.PW*1e-6))/1e6,(64/(h.PW*1e-6))/1e6);
                 case 9
                    B=min((6.2/sqrt(tr*1e-6*h.PWs))/1e6,(64/(h.PW*1e-6))/1e6);
                 case {6,7}
                    B = ((6.2/sqrt(h.PW*1e-6*tr*1e-6) + 2*(h.CBW*1e6+(0.105/(tr*1e-6))))/1e6);
                 case {2,8}
                    B=(0.0003*h.FC*1e6)/1e6;
                 case 5
                    B=((0.0003*h.FC*1e6)+ (h.CBW*1e6))/1e6;
                 otherwise
                    B=[];
              end
           case 'F'
              B=[];
        end

        end
    

        function B=B40LFM(app,Bc,t,tr,tf,n)
        % Function to Create -40 dB Pulsed FM Radar Mask
        % t = 50% voltage pulse width
        % Bc = chirp bandwidth
        % tr = rise time (10% - 90%) 
        % tf = fall time (90% - 10%) 
        % n  = implementation factor (number greater than 1)
        % B = -40 dB bandwidth
        
        dr=1.25*tr;
        df=1.25*tf;
        tb=t+0.5*(dr+df);
        bt=Bc*tb;
        Brise=1/sqrt(tb*dr);
        Bfall=1/sqrt(tb*df);
        Brf=1/(tb*df*dr)^(1/3);
        Bvec=[Brise Bfall Brf]; 
        
        if bt>10 && Bc*min(dr,df)>0.1
           B=n*Bc+n*sqrt(pi)*(log(bt)^0.53)*(min(Bvec)+max(Bvec));
        else
           B=((7.6/sqrt(2*t*1e-6*tr*1e-6)+ 2 * (Bc*1e6+(0.065/(tr*1e-6))))/1e6);
        end
        app = app;%#ok
        end


        function LoadFigureIcon(app,FigHand,ICON)
        
        warning('off','all');
        pause(1);
        figProps = struct(FigHand);
        controller = figProps.Controller;
        controllerProps = struct(controller);
        platformHost = controllerProps.PlatformHost;
        platformHostProps = struct(platformHost);
        win = platformHostProps.CEF;
        win.Icon = [pwd '\' ICON];
        app = app;%#ok
      
        end
    
        function [Fm,Sm] = GetSpanSettings(app,SpanSet,FC)
            
        Fm = linspace((FC)-SpanSet/2,(FC)+SpanSet/2,1000);
        Sm = -80*ones(size(Fm));
        
        app = app; %#ok
            
        end    
    
        function Y=getPG(app,h)
        app = app; %#ok    
        switch h.ModType
           case 3 
              Y=0;
           case 4
              Y=0; 
           case 6
              Y=10*log10(h.d);
           case 7
              Y=10*log10(h.d);   
           case 9
              Y=10*log10(h.N);
           otherwise
              Y=0;
        end
        end
    
        function X=getXdB(app,h)
        app = app; %#ok
        
        if isempty(h.Criteria)
        X=[];
        return
        end
        
        switch h.Criteria
          case 'A'
             switch h.ModType
                case {3,4,6,7,9,10}
                   X=max(60,h.Pt+30);
                case {2,5,8}
                   X=60;
                otherwise
                   X=[];
              end
          case 'B'
              switch h.ModType
                 case {3,4,6,7,9,10}
                    X=max(60,h.Pt+30);
                 case {2,5,8}
                    X=60;
                 otherwise
                    X=[];
              end
          case 'C'
              switch h.ModType
                 case {3,4,6,7,9,10}
                    X=max(60,h.Pt+30);
                 case {2,5,8}
                    X=60;
                 otherwise
                    X=[];
              end
          case 'D'
              switch h.ModType
                 case {3,4,6,7,9,10}
                    X=80;
                 case {2,5,8}
                    X=80;
                 otherwise
                    X=[];
              end
          case 'E'
              X=max(60,h.Pt+30);%60 for harmonics
           otherwise
              X=[];
        end

        end

        function [over,under]=ThreshCrossings(app,S,P)
        
        [n,~] = size(P);
        if n == 1 
        P = P';
        end
        
        [n,~] = size(S);
        if n == 1 
        S = S';
        end
        
        th1=find(S>P);
        over=[];
        under=ones((length(th1)-1),1);
        
        if ~isempty(th1)
           iover=1;
           over(iover)=th1(1);
           iunder=0;
           for i=2:length(th1)
              if th1(i)~=th1(i-1)+1
                 iunder=iunder+1;
                 under(iunder)=th1(i-1)+1;
                 iover=iover+1;
                 over(iover)=th1(i);
              end
           end
           if th1(end) < length(S)
              iunder=iunder+1;
              under(iunder)=th1(end)+1;
           end
        end
        app = app; %#ok
        end
    
        function AppData = LoadAll(app,AppData)
        AppData.RadarParams = []; %Clear exsiting Radar Prarameters
      
            
        Flags = MyGlobalFlags();
        
        %Load Panel Values and strings
        if isfield(AppData,'RadarFName')
        app.RadarName.Value = AppData.RadarFName; 
        AppData.RadarParams.RadarName = app.RadarName.Value;
        end
        
        if isfield(AppData,'SPSNumb')
        app.SPSNumber.Value = AppData.SPSNumb ;
        end
        
        if isfield(AppData,'SPSDat')
        app.SPSDate.Value = AppData.SPSDat ;
        end
        
        if isfield(AppData,'FRQLow')
        app.FRQLow.Value = str2double(AppData.FRQLow);
        AppData.RadarParams.FRQLow = app.FRQLow.Value;
        end
        
        if isfield(AppData,'FRQHi')
        app.FRQHigh.Value = str2double(AppData.FRQHi);
        AppData.RadarParams.FRQHigh = app.FRQHigh.Value;
        end
        
        if isfield(AppData,'FC')
            if ischar(AppData.FC)
            app.FRQCenter.Value = str2double(AppData.FC);
            else
            app.FRQCenter.Value = AppData.FC;
            end
        AppData.RadarParams.FRQCenter = app.FRQCenter.Value;
        end
        
        if isfield(AppData,'FL')
            if ischar(AppData.FL)
            app.FRQLow.Value = str2double(AppData.FL);
            else
            app.FRQLow.Value = AppData.FL;
            end
        AppData.RadarParams.FRQLow = app.FRQLow.Value;
        end
        
       if isfield(AppData,'FH')
            if ischar(AppData.FH)
            app.FRQHigh.Value = str2double(AppData.FH);
            else
            app.FRQHigh.Value = AppData.FH;
            end
        AppData.RadarParams.FRQHigh = app.FRQHigh.Value;
        end

        
        if isfield(AppData,'PP')
            if ischar(AppData.PP)
            app.PP.Value = str2double(AppData.PP);
            else
            app.PP.Value = AppData.PP;
            end
        AppData.RadarParams.TXPWRdBm = app.PP.Value;
        end
        
        if isfield(AppData,'Gt')
        app.AntGain.Value = str2double(AppData.Gt);
        AppData.RadarParams.AntennaGain = app.AntGain.Value;
        end
        
        if isfield(AppData,'RBW')
        app.RBW.Value = str2double(AppData.RBW);
        end
        
        if isfield(AppData,'ModType')
                if isempty(AppData.ModType)
                AppData.ModType = 1;
                end
        app.RadarModulationInfoDropDown.Value = app.RadarModulationInfoDropDown.Items{AppData.ModType};
        AppData.RadarParams.ModType = AppData.ModType;
        end
        
        if isfield(AppData,'Stage')
                if isempty(AppData.Stage)
                AppData.Stage = 1;
                end
        app.StageDropDown.Value = app.StageDropDown.Items{AppData.Stage} ;
        end
        
        
        if isfield(AppData,'Useage')
                if isempty(AppData.Useage)
                AppData.Useage = 1;
                end
        app.UsageDropDown.Value = app.UsageDropDown.Items{AppData.Useage};
        AppData.RadarParams.Useage = app.UsageDropDown.Value;
        end
        
        if AppData.ModType > 1
        Flags.ModSet = 1;
        end
        
        if Flags.RSMSdata
        if AppData.FC > 0
        Flags.FCSet = 1;
        end
        end
        
         
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  Check for field then load
               
        if isfield(AppData,'PW')
             if ischar(AppData.PW)
             MyGlobalAppData('PW',str2double(AppData.PW));
             AppData.RadarParams.PW = str2double(AppData.PW);
             AppData.PW = AppData.RadarParams.PW;
             else
             MyGlobalAppData('PW',AppData.PW); 
             AppData.RadarParams.PW = AppData.PW;
             end
        end
        
        if isfield(AppData,'PRT')
             if ischar(AppData.PRT)
             MyGlobalAppData('PRT',str2double(AppData.PRT));
             AppData.RadarParams.PRT = str2double(AppData.PRT);
             AppData.PRT = AppData.RadarParams.PRT;
             else
             MyGlobalAppData('PRT',AppData.PRT); 
             AppData.RadarParams.PRT = AppData.PRT;
             end
        end
        
        if isfield(AppData,'PFT')
             if ischar(AppData.PFT)
             MyGlobalAppData('PFT',str2double(AppData.PFT));
             AppData.RadarParams.PFT = str2double(AppData.PFT);
             AppData.PFT = AppData.RadarParams.PFT;
             else
             MyGlobalAppData('PFT',AppData.PFT); 
             AppData.RadarParams.PFT = AppData.PFT;
             end
        end
        
        if isfield(AppData,'PRR')
             if ischar(AppData.PRR)
             MyGlobalAppData('PRR',str2double(AppData.PRR));
             AppData.RadarParams.PRR = str2double(AppData.PRR);
             AppData.PRR = AppData.RadarParams.PRR;
             else
             MyGlobalAppData('PRR',AppData.PRR); 
             AppData.RadarParams.PRR = AppData.PRR;
             end
        end
        
        if isfield(AppData,'CBW')
             if ischar(AppData.CBW)
             MyGlobalAppData('CBW',str2double(AppData.CBW));
             AppData.RadarParams.CBW = str2double(AppData.CBW);
             AppData.CBW = AppData.RadarParams.CBW;
             else
             MyGlobalAppData('CBW',AppData.CBW); 
             AppData.RadarParams.CBW = AppData.CBW;
             end
        end
        
        if isfield(AppData,'Bs')
             if ischar(AppData.Bs)
             MyGlobalAppData('Bs',str2double(AppData.Bs));
             AppData.RadarParams.Bs = str2double(AppData.Bs);
             AppData.Bs = AppData.RadarParams.Bs;
             else
             MyGlobalAppData('Bs',AppData.Bs); 
             AppData.RadarParams.Bs = AppData.Bs;
             end
        end
        
        if isfield(AppData,'RadioLinear')
        MyGlobalAppData('RadioLinear',AppData.RadioLinear);
        AppData.RadarParams.RLinear = AppData.RadioLinear;
        end
        
        if isfield(AppData,'RadioNonLinear')
        MyGlobalAppData('RadioNonLinear',AppData.RadioNonLinear);
        AppData.RadarParams.RNLinear = AppData.RadioNonLinear;
        end
        
        MyGlobalAppData(AppData);
        end
        
        function h = PlotRSEC(app)
        
        AppData = MyGlobalAppData();
        Flags = MyGlobalFlags();
        h = AppData.RadarParams;
        
        h.oo = []; h.uu=[];
        
        h.Processdlg = uiprogressdlg(app.ComplianceToolsbyOSMNTIAUIFigure,'Title','Calculating Mask...',...
        'Indeterminate','on');
        
        if isfield(h,'PW') && ischar(h.PW)
        h.PW = str2double(h.PW);
        elseif ~isfield(h,'PW')
        h.PW = [];    
        end
        
        if isfield(h,'PRT') && ischar(h.PRT)
        h.PRT = str2double(h.PRT);
        elseif ~isfield(h,'PRT')
        h.PRT = [];    
        end
        
        if isfield(h,'PFT') && ischar(h.PFT)
        h.PFT = str2double(h.PFT);
        elseif ~isfield(h,'PFT')
        h.PFT = [];    
        end
        
        if isfield(h,'CBW')&& ischar(h.CBW)
        h.CBW = str2double(h.CBW);
        elseif ~isfield(h,'CBW')
        h.CBW = [];    
        end
        
        h.FC = app.FRQCenter.Value;
        
        
        if isempty(h.FC)
        h.FC=getFC(h.Fm,h.Sm);
        else
        if ischar(h.FC)    
        h.FC = str2double(h.FC);
        end
        end
        
        if ~isfield(h,'FL')
        h.FL = [];
        end
        
        if ~isfield(h,'FH')
        h.FH = [];
        end
        
        if isempty(h.FL)
        h.FL= 0;
        else
        if ischar(h.FL)    
        h.FL = str2double(h.FL);
        end
        end
        
        if isempty(h.FH)
        h.FH= 0;
        else
        if ischar(h.FH)    
        h.FL = str2double(h.FH);
        end
        end
        
     
        if h.FL ~= 0 && h.FH ~= 0
        SpanVal = h.FH - h.FL;    
        else
        SpanVal = h.FC;   %Dose this work in all cases??????????? 
        end    
        
        [h.Fm,h.Sm] = GetSpanSettings(app,SpanVal,h.FC); %Using above val for a default Span
        
        app.SpanMHzVal.Value = SpanVal;
    
        Fn = h.Fm - h.FC;
        h.FCM = h.FC;
        
        Idx = FindDropDownIndex(app,app.RadarModulationInfoDropDown);

        h.ModType = Idx;
        
        if h.ModType == 3 || h.ModType == 4 || h.ModType == 6 || h.ModType == 7 ||...
           h.ModType == 9 || h.ModType == 10
        
           dr = h.PRT*1.25;
           df = h.PFT*1.25;
           tb = h.PW + dr/2 + df/2;
           
        else
            
           h.PW = [];
           h.PRT = [];
           h.PRF = [];
           h.PRR = [];
           
        end
        
        if h.ModType == 6
           h.d = h.CBW * tb;
        else
           h.d=[];
        end
        
        Idx = FindDropDownIndex(app,app.UsageDropDown);
        UseNum = Idx;
        
        switch UseNum
            
            case {1,7}
            h.UseageN = 'Other';  

            case 2
            h.UseageN = 'Radio Navigation';  
        
            case 3    
            h.UseageN = 'WPR';  
        
            case 4
            h.UseageN = 'Fixed';  
       
            case 5
            h.UseageN = 'Expendable or Missile';  
        
            case 6
            h.UseageN = 'Man-Portable/Transporatable';  
            
            otherwise
            h.UseageN = 'Other';
            
        end
        
        if app.PP ~= 0 
        h.PP = app.PP.Value;
        else
        h.PP = [];
        end
        
        [h.RSECcrit,h.XdB] = nRSECcriteria(app,h);
        
        if ~isfield(h,'Criteria') || isempty(h.Criteria)
        h.Criteria = h.RSECcrit;
        end
        
        if ~isfield(h,'Bs') || isempty(h.Bs)
        h.Bs = 0;
        end
        
        h.bwRSECn=newRSECbw(app,h); %We plot the new RSEC
        h.bwRSECo=oldRSECbw(app,h); %We plot the current RSEC
        
        if ~isfield(h,'PG') || isempty(h.PG)
        h.PG = getPG(app,h); 
        end
             
        if ~isfield(h,'N')
        h.N = [];
        end
        
        if isfield(AppData,'PRR')
        h.PRR = AppData.PRR;
        else
        h.PRR = [];    
        end
        
        if isempty(h.N)
           if h.ModType == 3 || h.ModType == 6 || h.ModType == 4 || h.ModType == 7 || h.ModType == 10
           h.N = 1;
           else
           h.N = [];
           end
        end
        
        if ~isfield(h,'Pt')
        h.Pt = 0;
        end
        
        if isempty(h.Pt) && ~isempty(h.PP) && ~isempty(h.PRR)
            
           if ischar(h.PRR)
           h.PRR = str2double(h.PRR);
           end
        
        if isempty(h.PG) || ischar(h.PG)
        h.PG = 0;
        end
        
        h.Pt = h.PP + 20*log10(h.N*h.PW) + 10*log10(h.PRR)- h.PG - 90;
        end
        
            if ~isfield(h,'XdB')
            h.XdB = [];
            end
            
            if isempty(h.XdB)
            h.XdB = getXdB(app,h);
            end
        
        
        if isfield(h,'nSlope')
             if isempty(h.nSlope) 
             if max(strcmp(h.Criteria,{'','D','E'}))==1
             h.nSlope=40;
             else
             h.nSlope=30;
             end
             if strcmp(h.Criteria,'A')
             h.nSlope = 20; 
             end
             end
             
        
        else
                    if max(strcmp(h.Criteria,{'','D','E'}))==1
                    h.nSlope=40;
                    else
                    h.nSlope=30;
                    end  
                    if strcmp(h.Criteria,'A')
                    h.nSlope = 20;    
                    end
        end
        
        if max(strcmp(h.Criteria,{'','D','E'}))==1
           h.oSlope=40;
        else
           h.oSlope=20;
        end
        
        if strcmp(h.Criteria,{'F'}) || isempty(h.Criteria)
           h.nRollOff=zeros(length(h.Fm),1);
           h.oRollOff=zeros(length(h.Fm),1);
           h.oo=[];h.uu=[];
        else
            h.oRollOff=getMask(app,h.bwRSECo,h.XdB,h.oSlope,h.Fm-h.FCM,h);
            h.nRollOff=getMask(app,h.bwRSECn,h.XdB,h.nSlope,h.Fm-h.FCM,h);
            
            if Flags.OverUnder == 0
               [h.oo, h.uu] = ThreshCrossings(app,h.Sm,h.oRollOff);
               Flags.OverUnder = 1;
            end
        end
        
        
        if Flags.Normalize
        Fp = Fn;
        else
        Fp = h.Fm;
        end
        
        if h.ModType==4 || h.ModType==7 ||h.ModType==10
            
            if Flags.TextData
            Span = (Fn(end) - Fn(1)); 
            else
            Span = app.SpanMHzVal.Value;
            end 
            
        Fp = linspace(h.FC-(Span/2),(h.FC+Span/2),length(Fn));  
        
        end
        
        if isempty(h.Fm)
        Fs = h.FRQHigh - h.FRQLow;
        else
        Fs = h.Fm(end) - h.Fm(1);
        end
        
        if ischar(h.PRR)
        h.PRR = str2double(h.PRR);
        end
        
        switch h.ModType
           case {6,7}
              if isempty(h.PRR) || h.PRR == 0
                 warndlg('No PRR, therefore no theoretical spectrum');
                 uiwait;
                 h.Ft=h.FC;h.St=0;
              else
                 dc=h.PW*h.PRR*1e-6;
                 dc = checkdc(app,dc,Fs,h.PW,dr,df);%adjusts dc if necessary to avoid out of memory
                 [h.Ft,h.St] = Gen_Trap_Spect(app,0,h.CBW,h.PW,dr,df,dc,Fs);
                 h.St=h.St-max(h.St);
                 h.Ft=h.Ft+h.FC;
              end
              
           case {3,4}
              if isempty(h.PRR) || h.PRR == 0
                 warndlg('No PRR, therefore no theoretical spectrum');
                 uiwait;
                 h.Ft=h.FC;h.St=0;
              else
                 dc=h.PW*h.PRR*1e-6;
                 dc=checkdc(app,dc,Fs,h.PW,dr,df);
                 [h.Ft,h.St]=Gen_Trap_Spect(app,0,0,h.PW,dr,df,dc,Fs);
                 h.St=h.St-max(h.St);
                 h.Ft=h.Ft+h.FC;
              end
           otherwise
              h.Ft=h.FC;h.St=0;
        end
        
        if isfield(h, 'CMslope')
            if isempty(h.CMslope)
                h.CMslope=30;
            end    
        else
            h.CMslope=30;        
        end
        
        if isfield(h, 'CMxdb')
            if isempty(h.CMxdb)
                h.CMxdb=60;
            end    
        else
            h.CMxdb=60;        
        end
        
        if isfield(h, 'CMbw')
            if isempty(h.CMbw)
                h.CMbw=10;
            end    
        else
            h.CMbw=10;        
        end
        
        h.custMask=getMask(app,h.CMbw, h.CMxdb, h.CMslope, h.Fm-h.FCM,h);
       
        h.bw40dB = BWs(app,Fp,h.oRollOff,40,1); 
        h.bw20dB = BWs(app,Fp,h.oRollOff,20,1);
        
        h = RSECPlot(app,h,Fp,1);
        
        if Flags.MeasuredData
        app.MeasuredButton.Enable = 'On';
        RSECPlot(app,h,Fp,2);
        end
        
        AppData = MyGlobalAppData();
 
        app.ALabel.Text = h.Criteria;
        app.BW40Val.Text = num2str(h.bwRSECo);
        app.SlopeVal.Text = num2str(h.oSlope);
        app.XdBVal.Text = num2str(h.XdB);

        if Flags.ImportedData
        h.hlegend=legend(app.UIAxes,[AppData.UserData, AppData.RSEC_Mask, AppData.ProposedRSEC],h.RadarName,...
                         ['RSEC Mask ' h.Criteria ' ' num2str(h.oSlope) 'dB/dec'],...
                         ['FY2020 RSEC ' h.Criteria ' ' num2str(h.nSlope) 'dB/dec'],'Location','northeast');
        else
        h.hlegend=legend(app.UIAxes,[AppData.RSEC_Mask, AppData.ProposedRSEC],...
                         ['RSEC Mask ' h.Criteria ' ' num2str(h.oSlope) 'dB/dec'],...
                         ['FY2020 RSEC ' h.Criteria ' ' num2str(h.nSlope) 'dB/dec'],'Location','northeast');    
        end    
       
        app.UIAxes.FontSize = 12;
        h.hlegend.FontSize = 6;
        h.hlegend.Visible = 'On';
        h.X = get(app.UIAxes,'XLim');
        h.Y = get(app.UIAxes,'Ylim');%keep track of original axes extent
        
        delete(h.Processdlg);
        app.ExportFigureMenu.Enable = 'On';
        app.PrintMenu.Enable = 'On';
        AppData.RadarParams = h;
        MyGlobalAppData(AppData);
          
        end
        
        function [Data] = CreateRadarTable(app)
        
        fileName = [pwd '\RadarList.txt'];
        
        TableHead = {'Name','FL','FH','Tune','MinSep','OCBW',...
                     'EType','PPWR','AntBeamHZ','AntBeamVT',...
                     'AGT','AGR','TXHgt','RXHgt','POL','NFIG','Sens','XdB','PCR','FMBW',...
                     'PPS','PW','PRT','PFT','PDC','TXRF',...
                     'RXIF','Description'};
                 
        Radar.Data = readtable(fileName,'Delimiter', ',');
        Radar.Data.Properties.VariableNames = TableHead;
        
        Data = Radar.Data;
        
        disp('By your Command! Sir');
        app = app; %#ok;  
        end
      
        function MRData = MyGlobalMRData(app,Name,Value)
        persistent MRData_

        if isempty(MRData_)
        MRData_.PHCNum = [];
        MRData_.SPSNumb = [];
        MRData_.bw40 = [];
        MRData_.bwXdB = [];
        MRData_.DType = '';
        end    
        if nargin == 3
        MRData_.(Name) = Value;
        end
        
        if nargin == 2 
        MRData_ = Name;
        end
        
        MRData = MRData_;
        app = app; %#ok;       
        end
        
        function LoadAllParams(app)
        AppData = MyGlobalAppData();    
        app.RadarName.Value = AppData.RadarParams.RadarName;   
        app.FRQLow.Value = AppData.RadarParams.FRQLow;
        app.FRQHigh.Value = AppData.RadarParams.FRQHigh;
        app.FRQCenter.Value = AppData.RadarParams.FRQCenter;
        app.PP.Value = AppData.RadarParams.TXPWRdBm;
        app.AntGain.Value = AppData.RadarParams.AntennaGain;
        
        
        if isfield(AppData.RadarParams,'PW')
        MyGlobalAppData('PW',str2double(AppData.RadarParams.PW));
        end
        
        if isfield(AppData.RadarParams,'PRT')
        MyGlobalAppData('PRT',AppData.RadarParams.PRT);
        end
        
        if isfield(AppData.RadarParams,'PFT')
        MyGlobalAppData('PFT',AppData.RadarParams.PFT);
        end
        
        if isfield(AppData.RadarParams,'CBW')
        MyGlobalAppData('CBW',AppData.RadarParams.CBW);
        end
        
        if isfield(AppData.RadarParams,'PRR')
        tmp = AppData.RadarParams.PRR{:}; 
        
%         if length(tmp) > 1
%             if iscell(tmp)
%             tmp = str2double(tmp{2}) - str2double(tmp{1});
%             else
%             tmp = str2double(tmp(2)) - str2double(tmp(1));
%             end
%         else
%         tmp = str2double(tmp);    
%         end
        
        MyGlobalAppData('PRR',tmp);
        end
        AppData = MyGlobalAppData();
        app.UsageDropDown.Value = app.UsageDropDown.Items(4);
        app.StageDropDown.Value = app.StageDropDown.Items(5);
        app.SPSNumber.Value = AppData.RadarParams.EmitterType;
        app.SPSEditFieldLabel.Text = 'EMD:';
        
        A = strfind(AppData.RadarParams.EmitterType,'Q');
        B = strfind(AppData.RadarParams.EmitterType,'P');
        
        if A == 5
        app.RadarModulationInfoDropDown.Value = app.RadarModulationInfoDropDown.Items(6);
        elseif B == 5
        app.RadarModulationInfoDropDown.Value = app.RadarModulationInfoDropDown.Items(3);  
        end
               
        app = app; %#ok;    
        end
        
        function Idx = ReturnListIndex(app,List,Name)
        app = app; %#ok
        
        A = strfind(List,Name);
        
        Idx = [];
        
        Len = length(A);
            
                    for i = 1:Len
                        tmp = A{i};
                        if tmp == 1
                            Idx = i;
                            break;
                        end   
                    end    
            
                 
        end
        
    end   

    methods (Access = private)
        
        function UpdateTraceControl(app,Flag)
        
        if ~Flag
        app.RSECOldButton.Enable = 'Off';  
        app.RSECNewButton.Enable = 'Off';
        app.TheoryButton.Enable = 'Off';
        app.MeasuredButton.Enable = 'Off';
        app.OverageButton.Enable = 'Off';
        app.UnderageButton.Enable = 'Off';
        app.CustomButton.Enable = 'Off';
        app.RSECOldButton.Value = 0;  
        app.RSECNewButton.Value = 0;
        app.TheoryButton.Value = 0;
        app.MeasuredButton.Value = 0;
        app.OverageButton.Value = 0;
        app.UnderageButton.Value = 0;
        app.CustomButton.Value = 0;
        else
        app.RSECOldButton.Enable = 'On';  
        app.RSECNewButton.Enable = 'On';
        app.TheoryButton.Enable = 'On';
        app.MeasuredButton.Enable = 'On';
        app.OverageButton.Enable = 'On';
        app.UnderageButton.Enable = 'On';
        app.CustomButton.Enable = 'On';    
        end    
            
        end
    
        function Idx = FindDropDownIndex(app,ObjectHand)
        B = ObjectHand; 
        app = app;%#ok   
        
        A = B.Value;
        A = strfind(B.Items,A);
        
        Idx = [];
        
        Len = length(A);
            
                    for i = 1:Len
                        tmp = A{i};
                        if tmp == 1
                            Idx = i;
                            break;
                        end   
                    end    

        end
        
    end
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clc; addpath(pwd);clear vars
        app.ComplianceToolsbyOSMNTIAUIFigure.Position = [500,225,670,525];
        Icon = 'DOC_ICO.ico';
        LoadFigureIcon(app,app.ComplianceToolsbyOSMNTIAUIFigure,Icon);
        
        UpdateTraceControl(app,0);
        app.SpanMHzVal.Enable = 'On';
        app.RunRSECButton.Enable = 'Off';
        
        PA = app.UIAxes;
        PA.Title.String = 'RSEC MASK';
        PA.FontSize = 12;
        
        Flags = MyGlobalFlags(); 
        Flags.RunRSEC = 0;
        Flags.MeasuredData = 0;
        
        AppData = MyGlobalAppData(); 
        MRData = MyGlobalMRData(app); %#ok

        app.SPSDate.Value = date();
        
        [AppData.RadarList] = CreateRadarTable(app);
        MyGlobalAppData('RadarList',AppData.RadarList);
        
        RadarSelVal = vertcat('User Defined', table2cell(AppData.RadarList(:,1)));
        app.RadarDropDown.ItemsData = unique(RadarSelVal);
        app.RadarDropDown.Items = app.RadarDropDown.ItemsData;
               
        Idx = ReturnListIndex(app,app.RadarDropDown.ItemsData,'User Defined');
        app.RadarDropDown.Value =  app.RadarDropDown.ItemsData{Idx};          
        app.ViewMenu.Enable = 'Off';
        
        MyGlobalFlags(Flags);
        MyGlobalAppData(AppData);
        
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
        
        Flags = MyGlobalFlags();
        AppData = MyGlobalAppData();
        MRData = MyGlobalMRData(app);
        
        Flags.MD = 0;
        
        if isfield(AppData,'Fm')
        MRData.Fm = AppData.Fm; 
        Flags.MD = 1;
        end
        
        if isfield(AppData,'Sm')
        MRData.Sm = AppData.Sm; 
        Flags.MD = 1;
        end
        
        [SysName,SysDir,~] = uigetfile('*.mat','Select Radar file',pwd,'MultiSelect','off');
        
        
        figure(app.ComplianceToolsbyOSMNTIAUIFigure);
        
        Idx = ReturnListIndex(app,app.RadarDropDown.Items,'User Defined');
        tmp = app.RadarDropDown.Items(Idx);
        tmp = tmp{:};
        app.RadarDropDown.Value = tmp;  
        
                           
        if SysName==0
        return; %Perhapes a message dialog here?
        end
        
        load([SysDir '\' SysName]);%#ok
        
        if ~isfield(AppData,'FC')
        ed = errordlg('This is a RSMS data file please use Import menu','Error FT01');    
        waitfor(ed);
        return
        end
        
        AppData = LoadAll(app,AppData);
        MyGlobalAppData(AppData);
        
        app.RunRSECButton.Enable = 'On';
        app.OpenMenu.Enable = 'Off';
        
        AppData.PG = [];
        AppData.N = [];
        AppData.Pt = [];
        AppData.XdB = [];
        AppData.nSlope=[]; 
        AppData.t=[]; 
        AppData.tf=[]; 
        AppData.tr=[]; 
        AppData.ts=[];
        AppData.Useage = [];
        AppData.xmax=[];AppData.xmin=[];AppData.xstep=[];
        AppData.ymax=[];AppData.ymin=[];AppData.ystep=[];
        AppData.Bc=[];
        AppData.ResBW = app.RBW.Value;
        
        if isfield(MRData,'Fm') || Flags.MeasuredData
        AppData.Fm = MRData.Fm; 
        end
        if isfield(MRData,'Sm') || Flags.MeasuredData
        AppData.Sm = MRData.Sm; 
        end
        if isfield(MRData,'FL')
        AppData.RadarParams.FL = MRData.FL;
        app.FRQLow.Value = AppData.RadarParams.FL;
        end
        if isfield(MRData,'FH')
        AppData.RadarParams.FH = MRData.FH;
        app.FRQHigh.Value = AppData.RadarParams.FH;
        end
        
        Flags.OpenFile = 1; 
        Flags.RunRSEC = 0;
        app.RunRSECButton.Enable = 'On';

        MyGlobalAppData(AppData);
        MyGlobalFlags(Flags);
        MyGlobalMRData(app,MRData);
        
        end

        % Button pushed function: MyModPop
        function MyModPopButtonPushed(app, event)
        ModulationPop;    
        end

        % Value changed function: RadarDropDown
        function RadarDropDownValueChanged(app, event)
        AppData = MyGlobalAppData();
        AppData.EventLog = event;
        
        app.RadarDropDown.Items = app.RadarDropDown.ItemsData;
        RadarSelVal = app.RadarDropDown.Value;
        EQPName = RadarSelVal;
        AppData = LoadEQP(app,EQPName);
        SelectEDesignatorRSEC(AppData);
        AppData =  MyGlobalAppData();
        AppData = LoadEQP_RSEC(app,EQPName,AppData.EDSelected);   
        EQPListIndex = AppData.RP + (AppData.EDSelectedIndex - 1);
        
        if ~isempty(EQPListIndex) %Maybe you should clear all radar parameters if user input is selected?           
        AppData.RadarParams = GetRadarDataFromTable(EQPListIndex);
        MyGlobalAppData('RadarParams',AppData.RadarParams);
        LoadAllParams(app);
        end 
        
        app.RunRSECButton.Enable = 'On';
        end

        % Button pushed function: RunRSECButton
        function RunRSECButtonPushed(app, event)
        cla(app.UIAxes);
        
        app.RSECOldButton.Value = 1;
        app.MeasuredButton.Value = 0;
        
        app.RSECOldButton.Enable = 'On';
        app.RSECNewButton.Enable = 'On';
        app.TheoryButton.Enable = 'On';
        app.OpenMenu.Enable = 'Off';
        
        PlotRSEC(app); 
        AppData = MyGlobalAppData();
        Flags = MyGlobalFlags();
        Flags.RunRSEC = 1;
        
        if Flags.SpanSet
        [Fm,~] = GetSpanSettings(app,app.SpanMHzVal.Value,app.FRQCenter.Value);
        app.UIAxes.XLim = [min(Fm) max(Fm)];
        else
            if app.FRQLow.Value ~= 0 && app.FRQHigh.Value ~= 0
            AppData.FL = app.FRQLow.Value;AppData.FH = app.FRQHigh.Value;    
            XMin = AppData.FL;XMax = AppData.FH;
            else
            XMin = app.FRQCenter.Value - (app.SpanMHzVal.Value/2); 
            XMax = app.FRQCenter.Value + (app.SpanMHzVal.Value/2); 
            end
        app.UIAxes.XLim = [XMin XMax];
        end
        
        app.YLIMVal.Value = min(app.UIAxes.YLim);
        app.RunRSECButton.Enable = 'Off';
        MyGlobalFlags(Flags);
        MyGlobalAppData(AppData);
        end

        % Value changed function: RSECOldButton
        function RSECOldButtonValueChanged(app, event)
        value = app.RSECOldButton.Value;
        AppData = MyGlobalAppData();
            
        if value == 0
        AppData.RSEC_Mask.Visible = 'Off';
        else
        AppData.RSEC_Mask.Visible = 'ON';
        end
        app.YLIMVal.Value = min(app.UIAxes.YLim);    
        end

        % Value changed function: RSECNewButton
        function RSECNewButtonValueChanged(app, event)
        value = app.RSECNewButton.Value;
        AppData = MyGlobalAppData();
        
        if value == 0
        AppData.ProposedRSEC.Visible = 'Off';
        else
        AppData.ProposedRSEC.Visible = 'ON';
        end
        app.YLIMVal.Value = min(app.UIAxes.YLim);       
        end

        % Value changed function: TheoryButton
        function TheoryButtonValueChanged(app, event)
        value = app.TheoryButton.Value;
        AppData = MyGlobalAppData();
        h = AppData.RadarParams;
        
        if value == 0
        AppData.Theory.Visible = 'Off';
        else
        AppData.Theory.Visible = 'ON';
        legend(app.UIAxes,[AppData.RSEC_Mask, AppData.ProposedRSEC, AppData.Theory],...
                          ['RSEC Mask ' h.Criteria ' ' num2str(h.oSlope) 'dB/dec'],...
                          ['FY2020 RSEC ' h.Criteria ' ' num2str(h.nSlope) 'dB/dec'],...
                          'Theoretical (FFT)','Location','northeast');    
        end
        app.YLIMVal.Value = min(app.UIAxes.YLim);    
        end

        % Value changed function: MeasuredButton
        function MeasuredButtonValueChanged(app, event)
        value = app.MeasuredButton.Value;
        AppData = MyGlobalAppData();
        h = AppData.RadarParams;
        
        Flags = MyGlobalFlags();
        
        if Flags.SpanSet
        [Fm,~] = GetSpanSettings(app,app.SpanMHzVal.Value,app.FRQCenter.Value);
        app.UIAxes.XLim = [min(Fm) max(Fm)];
        end
        
        RName = cell2mat(app.TextFName.Text);
        A = ['RSEC Mask ' h.Criteria ' ' num2str(h.oSlope) 'dB/dec'];
        B = ['FY2020 RSEC ' h.Criteria ' ' num2str(h.nSlope) 'dB/dec'];
        C = 'Theoretical (FFT)';
        D = ['Measured Data: ' RName];
        LL = {A,B,C,D};
        
        if value == 0
        AppData.UserData.Visible = 'Off';
        else
        AppData.UserData.Visible = 'On';
        legend(app.UIAxes,LL,'Location','northeast');    
        end
        app.YLIMVal.Value = min(app.UIAxes.YLim);  
        app.SpanMHzVal.Value = app.UIAxes.XLim(2) - app.UIAxes.XLim(1);
        end

        % Menu selected function: TextFileMenu
        function TextFileMenuSelected(app, event)
        ImportMeasuredData(app); 

        end

        % Menu selected function: ViewMenu
        function ViewMenuSelected(app, event)
        PopUpPlot(app);    
        app.ViewMenu.Enable = 'Off';
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
        AppData = MyGlobalAppData();    
        IP = AppData.ImportPath; 
        cla(app.UIAxes); 
        app.UIAxes.XLim = [0 1];
        if ~isempty(app.UIAxes.Legend)
        app.UIAxes.Legend.Visible = 'Off';
        end
        
        AppData = [];
        Flags = [];
        MRData = [];
        MyGlobalAppData(AppData);
        MyGlobalAppData('ImportPath',IP);
        MyGlobalFlags(Flags);
        MyGlobalMRData(app,MRData);
        app.OpenMenu.Enable = 'On';
        app.TextFileMenu.Enable = 'On';
        app.ExportFigureMenu.Enable = 'Off';
        app.PrintMenu.Enable = 'Off';
        UpdateTraceControl(app,0);
        app.RSECOldButton.Value = 0;
        app.PP.Value = 0;
        app.FRQLow.Value = 0;
        app.FRQHigh.Value = 0;
        app.FRQCenter.Value = 0;
        app.AntGain.Value = 0;
        app.RadarName.Value = 'Radar X';
        app.SPSNumber.Value = 'Number Here';
        app.TextFName.Text = '**********';
        app.BWFactor.Text = '*********';
        app.SPSEditFieldLabel.Text = 'SPS#:';
        
        MyGlobalFlags('NewStart',1);
        MyGlobalFlags('RunRSEC',0);
        startupFcn(app);

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
        Save_Callback(app);    
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
        Exit_Callback(app);    
        end

        % Value changed function: FRQCenter
        function FRQCenterValueChanged(app, event)
        %value = app.FRQCenter.Value;
        %app.RunRSECButton.Enable = 'On';
        RunRSECButtonPushed(app, 1);
        end

        % Value changed function: SpanMHzVal
        function SpanMHzValValueChanged(app, event)
        SpanVal = app.SpanMHzVal.Value;
       
        
        FL = app.FRQCenter.Value - (SpanVal/2);
        FH = app.FRQCenter.Value + (SpanVal/2);
            
        app.UIAxes.XLim = [FL FH];
            
        MyGlobalFlags('SpanSet',1);    
        end

        % Value changed function: YLIMVal
        function YLIMValValueChanged(app, event)
        YVal = app.YLIMVal.Value;
        
        YH = 0;
        YL = YVal;
        
        app.UIAxes.YLim = [YL YH];
            
        MyGlobalFlags('AmpSet',1);    
            
        end

        % Menu selected function: ExportFigureMenu
        function ExportFigureMenuSelected(app, event)
        AppData = MyGlobalAppData();

        IconLocation = [pwd '\Radar.png'];
        AppData.OutDir = pwd;
        
        if strcmp(app.UIAxes.Visible,'on')
            
              [AppData.RadarFName,AppData.OutDir]= uiputfile('*.png','Save figure as',...
              [pwd '\' AppData.RadarParams.RadarName '_' num2str(AppData.RadarParams.nSlope) '.png']);
          
           if AppData.OutDir==0
              WD = warndlg('Export Was Cancelled','NTIA OSM: Warning!!'); 
              warning('off','MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame');
              jframe=get(WD,'javaframe');
              jIcon=javax.swing.ImageIcon(IconLocation);
              jframe.setFigureIcon(jIcon);  
              return
           end
           
           fileName = [AppData.OutDir AppData.RadarFName];
           h = Export_CallPlot(app);
           saveas(h,fileName);
           close(h);
           fileName = []; %#ok Clear the fileName
          
        else
           WD = warndlg('Need to Plot before exporting the figure','NTIA OSM: Warning!!');
           warning('off','MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame');
           jframe=get(WD,'javaframe');
           jIcon=javax.swing.ImageIcon(IconLocation);
           jframe.setFigureIcon(jIcon);   
           
        end
    
        end

        % Menu selected function: PrintMenu
        function PrintMenuSelected(app, event)
        hand = Export_CallPlot(app);
        orient landscape;
        printdlg(hand);
        close(hand);
        end

        % Menu selected function: AboutRCPMenu
        function AboutRCPMenuSelected(app, event)
        Help_AboutRSEC      
        end

        % Value changed function: FRQLow
        function FRQLowValueChanged(app, event)
            value = app.FRQLow.Value;
            AppData = MyGlobalAppData();
            AppData.FL = value;
            AppData.RadarParams.FL = value;
            MyGlobalAppData(AppData);
        end

        % Value changed function: FRQHigh
        function FRQHighValueChanged(app, event)
            value = app.FRQHigh.Value;
            AppData = MyGlobalAppData();
            AppData.FH = value;
            AppData.RadarParams.FH = value;
            MyGlobalAppData(AppData);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComplianceToolsbyOSMNTIAUIFigure = uifigure;
            app.ComplianceToolsbyOSMNTIAUIFigure.Name = 'Compliance Tools by OSM NTIA';
            app.FileMenu_2 = uimenu(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.FileMenu_2.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu_2);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu_2);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ImportMenu = uimenu(app.FileMenu_2);
            app.ImportMenu.Text = 'Import';
            app.TextFileMenu = uimenu(app.ImportMenu);
            app.TextFileMenu.MenuSelectedFcn = createCallbackFcn(app, @TextFileMenuSelected, true);
            app.TextFileMenu.Text = 'Text File';
            app.ManualDataMenu = uimenu(app.ImportMenu);
            app.ManualDataMenu.Enable = 'off';
            app.ManualDataMenu.Text = 'Manual Data';
            app.SaveMenu = uimenu(app.FileMenu_2);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu_2);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ExecuteMenu = uimenu(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.ExecuteMenu.Text = 'Execute';
            app.PrintMenu = uimenu(app.ExecuteMenu);
            app.PrintMenu.MenuSelectedFcn = createCallbackFcn(app, @PrintMenuSelected, true);
            app.PrintMenu.Enable = 'off';
            app.PrintMenu.Text = 'Print';
            app.ExportFigureMenu = uimenu(app.ExecuteMenu);
            app.ExportFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFigureMenuSelected, true);
            app.ExportFigureMenu.Enable = 'off';
            app.ExportFigureMenu.Text = 'Export Figure';
            app.ViewMenu = uimenu(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.ViewMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutRCPMenu = uimenu(app.HelpMenu);
            app.AboutRCPMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutRCPMenuSelected, true);
            app.AboutRCPMenu.Text = 'About RCP';
            app.NTIARSECComplianceProgramRCPLabel = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.NTIARSECComplianceProgramRCPLabel.Text = 'NTIA RSEC Compliance Program (RCP)';
            app.UIAxes = uiaxes(app.ComplianceToolsbyOSMNTIAUIFigure);
            title(app.UIAxes, 'RSEC Mask')
            xlabel(app.UIAxes, 'Frequency (MHz)')
            ylabel(app.UIAxes, 'Normalized Rejection (dB)')
            app.RadarInfoPanel = uipanel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.RadarInfoPanel.Title = 'Radar Info:';
            app.RadarName = uieditfield(app.RadarInfoPanel, 'text');
            app.RadarName.Value = 'Radar X';
            app.StageDropDownLabel = uilabel(app.RadarInfoPanel);
            app.StageDropDownLabel.Text = 'Stage:';
            app.StageDropDown = uidropdown(app.RadarInfoPanel);
            app.StageDropDown.Items = {'Select Stage >>', 'Conceptional', 'Experimental', 'Developmental', 'Operational'};
            app.StageDropDown.Value = 'Select Stage >>';
            app.SPSEditFieldLabel = uilabel(app.RadarInfoPanel);
            app.SPSEditFieldLabel.Text = 'SPS#:';
            app.SPSNumber = uieditfield(app.RadarInfoPanel, 'text');
            app.SPSNumber.Value = 'Number Here';
            app.DateEditFieldLabel = uilabel(app.RadarInfoPanel);
            app.DateEditFieldLabel.Text = 'Date:';
            app.SPSDate = uieditfield(app.RadarInfoPanel, 'text');
            app.RadarDropDown = uidropdown(app.RadarInfoPanel);
            app.RadarDropDown.Items = {'User Defined '};
            app.RadarDropDown.ValueChangedFcn = createCallbackFcn(app, @RadarDropDownValueChanged, true);
            app.RadarDropDown.Value = 'User Defined ';
            app.RadarModulationInfoPanel = uipanel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.RadarModulationInfoPanel.Title = 'Radar Modulation Info:';
            app.RadarModulationInfoDropDown = uidropdown(app.RadarModulationInfoPanel);
            app.RadarModulationInfoDropDown.Items = {'Select Modulation Type', 'CW (unmodulated)', 'Pulse (unmodulated)', 'Pulse (unmodulated w/FHOP)', 'CW (intentional FM)', 'Pulse (intentional FM)', 'Pulse (intentional FM w/FHOP)', 'CW (phase coded)', 'Pulse (phase coded)', 'Pulse (phase coded w/FHOP)'};
            app.RadarModulationInfoDropDown.Value = 'Select Modulation Type';
            app.MyModPop = uibutton(app.RadarModulationInfoPanel, 'push');
            app.MyModPop.ButtonPushedFcn = createCallbackFcn(app, @MyModPopButtonPushed, true);
            app.MyModPop.Text = 'Modulation Settings';
            app.RadarSettingsPanel = uipanel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.RadarSettingsPanel.Title = 'Radar Settings:';
            app.FLEditFieldLabel = uilabel(app.RadarSettingsPanel);
            app.FLEditFieldLabel.Text = 'FL';
            app.FRQLow = uieditfield(app.RadarSettingsPanel, 'numeric');
            app.FRQLow.Limits = [0 25000000];
            app.FRQLow.ValueChangedFcn = createCallbackFcn(app, @FRQLowValueChanged, true);
            app.FHEditFieldLabel = uilabel(app.RadarSettingsPanel);
            app.FHEditFieldLabel.Text = 'FH';
            app.FRQHigh = uieditfield(app.RadarSettingsPanel, 'numeric');
            app.FRQHigh.Limits = [0 25000000];
            app.FRQHigh.ValueChangedFcn = createCallbackFcn(app, @FRQHighValueChanged, true);
            app.FCEditFieldLabel = uilabel(app.RadarSettingsPanel);
            app.FCEditFieldLabel.Text = 'FC';
            app.FRQCenter = uieditfield(app.RadarSettingsPanel, 'numeric');
            app.FRQCenter.Limits = [0 25000000];
            app.FRQCenter.ValueChangedFcn = createCallbackFcn(app, @FRQCenterValueChanged, true);
            app.PWREditFieldLabel = uilabel(app.RadarSettingsPanel);
            app.PWREditFieldLabel.Text = 'PWR';
            app.PP = uieditfield(app.RadarSettingsPanel, 'numeric');
            app.PP.Limits = [0 25000000];
            app.GTEditFieldLabel = uilabel(app.RadarSettingsPanel);
            app.GTEditFieldLabel.Text = 'GT';
            app.AntGain = uieditfield(app.RadarSettingsPanel, 'numeric');
            app.AntGain.Limits = [0 25000000];
            app.RBWEditFieldLabel = uilabel(app.RadarSettingsPanel);
            app.RBWEditFieldLabel.Text = 'RBW';
            app.RBW = uieditfield(app.RadarSettingsPanel, 'numeric');
            app.RBW.Limits = [0 25000000];
            app.UsageDropDown = uidropdown(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.UsageDropDown.Items = {'Select Usage >>', 'Radio Navigation', 'WPR', 'Fixed', 'Expendable', 'Man Portable', 'Other'};
            app.UsageDropDown.Value = 'Select Usage >>';
            app.RunRSECButton = uibutton(app.ComplianceToolsbyOSMNTIAUIFigure, 'push');
            app.RunRSECButton.ButtonPushedFcn = createCallbackFcn(app, @RunRSECButtonPushed, true);
            app.RunRSECButton.Icon = 'NTIA.png';
            app.RunRSECButton.Text = 'Run RSEC';
            app.BWFactor = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.BWFactor.Text = '**************';
            app.TraceControlPanel = uipanel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.TraceControlPanel.Title = 'Trace Control';
            app.RSECOldButton = uibutton(app.TraceControlPanel, 'state');
            app.RSECOldButton.ValueChangedFcn = createCallbackFcn(app, @RSECOldButtonValueChanged, true);
            app.RSECOldButton.Enable = 'off';
            app.RSECOldButton.Text = 'RSEC Old';
            app.RSECNewButton = uibutton(app.TraceControlPanel, 'state');
            app.RSECNewButton.ValueChangedFcn = createCallbackFcn(app, @RSECNewButtonValueChanged, true);
            app.RSECNewButton.Enable = 'off';
            app.RSECNewButton.Text = 'RSEC New';
            app.TheoryButton = uibutton(app.TraceControlPanel, 'state');
            app.TheoryButton.ValueChangedFcn = createCallbackFcn(app, @TheoryButtonValueChanged, true);
            app.TheoryButton.Enable = 'off';
            app.TheoryButton.Text = 'Theory';
            app.MeasuredButton = uibutton(app.TraceControlPanel, 'state');
            app.MeasuredButton.ValueChangedFcn = createCallbackFcn(app, @MeasuredButtonValueChanged, true);
            app.MeasuredButton.Enable = 'off';
            app.MeasuredButton.Text = 'Measured';
            app.OverageButton = uibutton(app.TraceControlPanel, 'state');
            app.OverageButton.Enable = 'off';
            app.OverageButton.Text = 'Overage';
            app.UnderageButton = uibutton(app.TraceControlPanel, 'state');
            app.UnderageButton.Enable = 'off';
            app.UnderageButton.Text = 'Underage';
            app.CustomButton = uibutton(app.TraceControlPanel, 'state');
            app.CustomButton.Enable = 'off';
            app.CustomButton.Text = 'Custom';
            app.CriteriaLabel = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.CriteriaLabel.Text = 'Criteria: ';
            app.ALabel = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.ALabel.Text = 'A';
            app.BW40dBLabel = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.BW40dBLabel.Text = 'BW40dB:';
            app.BW40Val = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.BW40Val.Text = 'XX';
            app.SlopeLabel = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.SlopeLabel.Text = 'Slope:';
            app.SlopeVal = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.SlopeVal.Text = 'XX';
            app.XdBLabel = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.XdBLabel.Text = 'XdB:';
            app.XdBVal = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.XdBVal.Text = 'XX';
            app.SpanMHzEditFieldLabel = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.SpanMHzEditFieldLabel.Text = 'Span (MHz)';
            app.SpanMHzVal = uieditfield(app.ComplianceToolsbyOSMNTIAUIFigure, 'numeric');
            app.SpanMHzVal.Limits = [5 Inf];
            app.SpanMHzVal.ValueChangedFcn = createCallbackFcn(app, @SpanMHzValValueChanged, true);
            app.SpanMHzVal.Value = 100;
            app.TextFName = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.TextFName.Text = '**************';
            app.YLimdBmLabel = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.YLimdBmLabel.Text = 'YLim (dBm)';
            app.YLIMVal = uieditfield(app.ComplianceToolsbyOSMNTIAUIFigure, 'numeric');
            app.YLIMVal.Limits = [-Inf 0];
            app.YLIMVal.ValueChangedFcn = createCallbackFcn(app, @YLIMValValueChanged, true);
            app.YLIMVal.Value = -100;
            app.RevX10Label = uilabel(app.ComplianceToolsbyOSMNTIAUIFigure);
            app.RevX10Label.Text = 'Rev X1.0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RSEC_Main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComplianceToolsbyOSMNTIAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComplianceToolsbyOSMNTIAUIFigure)
        end
    end
end